classdef NiiStatGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MAINTab                         matlab.ui.container.Tab
        SelectExcelFileButton           matlab.ui.control.Button
        ExcelFileLabel                  matlab.ui.control.Label
        ExcelFileEditField              matlab.ui.control.EditField
        NameForResultsFolderLabel       matlab.ui.control.Label
        NameForResultsFolderEditField   matlab.ui.control.EditField
        SelectOutputDirectoryButton     matlab.ui.control.Button
        OutputDirLabel                  matlab.ui.control.Label
        OutputDirEditField              matlab.ui.control.EditField
        SelectDataDirectoryButton       matlab.ui.control.Button
        DataDirLabel                    matlab.ui.control.Label
        DataDirEditField                matlab.ui.control.EditField
        GOButton                        matlab.ui.control.Button
        CorrectedpThresholdEditFieldLabel  matlab.ui.control.Label
        CorrectedpThresholdEditField    matlab.ui.control.NumericEditField
        minimumoverlapEditFieldLabel    matlab.ui.control.Label
        minimumoverlapEditField         matlab.ui.control.NumericEditField
        numberofpermutationsifPermutationsorFreedmanLaneEditFieldLabel  matlab.ui.control.Label
        numperms                        matlab.ui.control.NumericEditField
        testtype                        matlab.ui.container.ButtonGroup
        FDRButton                       matlab.ui.control.RadioButton
        BoneferroniButton               matlab.ui.control.RadioButton
        NormalPermutationButton         matlab.ui.control.RadioButton
        FreedmanLanePermutationButton   matlab.ui.control.RadioButton
        Button_19                       matlab.ui.control.StateButton
        TextArea_9                      matlab.ui.control.TextArea
        EditField_2                     matlab.ui.control.EditField
        UseNiiStatClassicCheckBox       matlab.ui.control.CheckBox
        ATLASTab                        matlab.ui.container.Tab
        TextArea_1                      matlab.ui.control.TextArea
        ROIs_1                          matlab.ui.control.ListBox
        TextArea_2                      matlab.ui.control.TextArea
        ROIs_2                          matlab.ui.control.ListBox
        aichaButton                     matlab.ui.control.StateButton
        TextArea_6                      matlab.ui.control.TextArea
        ROIs_6                          matlab.ui.control.ListBox
        foxButton                       matlab.ui.control.StateButton
        TextArea_7                      matlab.ui.control.TextArea
        ROIsListBoxLabel_4              matlab.ui.control.Label
        ROIs_7                          matlab.ui.control.ListBox
        aalButton                       matlab.ui.control.StateButton
        TextArea_3                      matlab.ui.control.TextArea
        catButton                       matlab.ui.control.StateButton
        TextArea_4                      matlab.ui.control.TextArea
        ROIs_4                          matlab.ui.control.ListBox
        aalcatButton                    matlab.ui.control.StateButton
        TextArea_5                      matlab.ui.control.TextArea
        ROIs_5                          matlab.ui.control.ListBox
        voxelwiseButton                 matlab.ui.control.StateButton
        ROIs_3                          matlab.ui.control.ListBox
        jhuButton                       matlab.ui.control.StateButton
        broButton                       matlab.ui.control.StateButton
        EditField                       matlab.ui.control.EditField
        SELECTROISUBSETButtonGroup      matlab.ui.container.ButtonGroup
        OriginalButton                  matlab.ui.control.ToggleButton
        IowaButton                      matlab.ui.control.ToggleButton
        YoungAdultButton                matlab.ui.control.ToggleButton
        TextArea_VOXELWISE              matlab.ui.control.TextArea
        cataniButton                    matlab.ui.control.StateButton
        TextArea_8                      matlab.ui.control.TextArea
        ROIs_8                          matlab.ui.control.ListBox
        CustomROIsSwitchLabel           matlab.ui.control.Label
        CustomROIsSwitch                matlab.ui.control.ToggleSwitch
        MODALITYTab                     matlab.ui.container.Tab
        LESIONButton                    matlab.ui.control.StateButton
        CBFButton                       matlab.ui.control.StateButton
        RESTButton                      matlab.ui.control.StateButton
        i3mT1Button                     matlab.ui.control.StateButton
        MTTButton                       matlab.ui.control.StateButton
        DTIMDButton                     matlab.ui.control.StateButton
        TTPButton                       matlab.ui.control.StateButton
        DTIFAButton                     matlab.ui.control.StateButton
        i3mT2Button                     matlab.ui.control.StateButton
        CBVButton                       matlab.ui.control.StateButton
        DTIFCButton                     matlab.ui.control.StateButton
        fMRIButton                      matlab.ui.control.StateButton
        fMRIbButton                     matlab.ui.control.StateButton
        RESTalfButton                   matlab.ui.control.StateButton
        RESTpalfButton                  matlab.ui.control.StateButton
        DTIMKButton                     matlab.ui.control.StateButton
        Button_11                       matlab.ui.control.StateButton
        Button_12                       matlab.ui.control.StateButton
        Button_13                       matlab.ui.control.StateButton
        Button_14                       matlab.ui.control.StateButton
        Button_15                       matlab.ui.control.StateButton
        Button_16                       matlab.ui.control.StateButton
        DTIButton                       matlab.ui.control.StateButton
        Button_18                       matlab.ui.control.StateButton
        SPECIALTab                      matlab.ui.container.Tab
        Button_20                       matlab.ui.control.StateButton
        Button_21                       matlab.ui.control.StateButton
        interhemispheric                matlab.ui.control.StateButton
        ExplicitVoxelMaskSwitchLabel    matlab.ui.control.Label
        ExplicitVoxelMaskSwitch         matlab.ui.control.ToggleSwitch
        RegressLesionVolumeSwitchLabel  matlab.ui.control.Label
        RegressLesionVolumeSwitch       matlab.ui.control.ToggleSwitch
        DeskewDataSwitchLabel           matlab.ui.control.Label
        DeskewDataSwitch                matlab.ui.control.ToggleSwitch
        IncludeWMCSFconnectivitySwitchLabel  matlab.ui.control.Label
        IncludeWMCSFconnectivitySwitch  matlab.ui.control.ToggleSwitch
        TFCESwitchLabel                 matlab.ui.control.Label
        TFCESwitch                      matlab.ui.control.ToggleSwitch
        ReportROIMeansSwitchLabel       matlab.ui.control.Label
        ReportROIMeansSwitch            matlab.ui.control.ToggleSwitch
        SVMSwitchLabel                  matlab.ui.control.Label
        SVMSwitch                       matlab.ui.control.ToggleSwitch
        LowResolutionSwitchLabel        matlab.ui.control.Label
        LowResolutionSwitch             matlab.ui.control.ToggleSwitch
        ChooseMaskButton                matlab.ui.control.Button
        ChosenMaskEditField             matlab.ui.control.EditField
        ChooseCustomROIsFilesinadditiontoatlasesButton  matlab.ui.control.Button
        ClearMaskButton                 matlab.ui.control.Button
        ClearROIsButton                 matlab.ui.control.Button
        chosenroifilesTextArea          matlab.ui.control.TextArea
        LimitanalysistolefthemisphererighthemisphereorbilateralLabel  matlab.ui.control.Label
        SPECIALTab_2                    matlab.ui.container.Tab
    end


    methods (Access = private)

        % Code that executes after component creation
        function StartupFunction(app)
            
            roifiles = GetROIFilesGivenDirectory('roi/');
            %populate roifiles
            %app.ROIS_one.Items = importdata(fullfile(roifolder,images(i).name));
            %default is typical ROIs selected
            app.ROIs_1.Items = GetROINamesFromFile(fullfile(GetROIPathGivenDirectoryName('roi/'),'bro.txt'),4);        app.TextArea_1.Value = 'Brodmann';
            app.ROIs_2.Items = GetROINamesFromFile(fullfile(GetROIPathGivenDirectoryName('roi/'),'jhu.txt'),4);        app.TextArea_2.Value = 'JHU';
            app.ROIs_3.Items = GetROINamesFromFile(fullfile(GetROIPathGivenDirectoryName('roi/'),'aal.txt'),4);        app.TextArea_3.Value = 'aal';
            app.ROIs_4.Items = GetROINamesFromFile(fullfile(GetROIPathGivenDirectoryName('roi/'),'cat.txt'),4);        app.TextArea_4.Value = 'cat';
            app.ROIs_5.Items = GetROINamesFromFile(fullfile(GetROIPathGivenDirectoryName('roi/'),'aalcat.txt'),4);     app.TextArea_5.Value = 'aalcat';
            app.ROIs_6.Items = GetROINamesFromFile(fullfile(GetROIPathGivenDirectoryName('roi/'),'AICHA.txt'),4);      app.TextArea_6.Value = 'AICHA';
            app.ROIs_7.Items = GetROINamesFromFile(fullfile(GetROIPathGivenDirectoryName('roi/'),'fox.txt'),4);        app.TextArea_7.Value = 'fox';
           
            %Fill in blanks from prior cfg file so don't need to reload them
            %if no such directories or excel files then fill in some default values
            orient = which('NiiStatGUI');
            [path file] = fileparts(orient);
            
            if exist(fullfile(path,'niistatGUI_cfg.mat'),'file') == 2
                priorGUI = load(fullfile(path,'niistatGUI_cfg.mat'));
                
                if exist(priorGUI.GUIdata.dataDirectory) == 7
                    app.DataDirEditField.Value = priorGUI.GUIdata.dataDirectory;
                else
                    app.DataDirEditField.Value = pwd;
                end
                
                if exist(priorGUI.GUIdata.outputDirectory) == 7 
                    app.OutputDirEditField.Value = priorGUI.GUIdata.outputDirectory;
                else
                    app.OutputDirEditField.Value = pwd;
                end
                
                if exist(priorGUI.GUIdata.excelFile) == 2
                    app.ExcelFileEditField.Value = priorGUI.GUIdata.excelFile;
                else
                    app.ExcelFileEditField.Value = fullfile(pwd,'excelfile.xlsx');
                end
                
                app.NameForResultsFolderEditField.Value = priorGUI.GUIdata.resultsFolder; 
                app.numperms.Value = priorGUI.GUIdata.perms;
                app.CorrectedpThresholdEditField.Value = priorGUI.GUIdata.pvalue;
                
                %would be nice to set the selections too, but not trivial
                %app.ROIs_1.Value = priorGUI.GUIdata.brodmann_picks_raw 
               
            end
            
             
        end

        % Callback function
        function SelectExcelFileButtonPushed(app, event)
          [efile, epth] = uigetfile(fullfile(app.DataDirEditField.Value,'*.xlsx;*.xls'));
          excelName = [epth efile];
          app.ExcelFileEditField.Value = excelName;
        end

        % Callback function
        function SelectDataDirectoryButtonPushed(app, event)
           app.DataDirEditField.Value = uigetdir();
        end

        % Callback function
        function SelectOutputDirectoryButtonPushed(app, event)
           app.OutputDirEditField.Value = uigetdir();
        end

        % Callback function
        function ResultsFolderNameEditFieldValueChanged(app, event)
           resultsFolderName = app.NameForResultsFolderEditField.Value;
        end

        % Button pushed function: ChooseMaskButton
        function ChooseMaskButtonPushed(app, event)
          [mfile, mpth] = uigetfile(fullfile(app.DataDirEditField.Value,'*.nii;*.nii.gz'));
          maskName = [mpth mfile];
          app.ChosenMaskEditField.Value = maskName;
        end

        % Button pushed function: 
        % ChooseCustomROIsFilesinadditiontoatlasesButton
        function ChooseCustomROIsFilesinadditiontoatlasesButtonPushed(app, event)
          [chosenrois,chosenpath] = uigetfile('*.*', 'All Files (*.*)','MultiSelect','on');
          len = length(chosenrois)
          for i = 1:len
             textboxdata{i,1} = [chosenpath char(chosenrois{i})];     
          end
          app.chosenroifilesTextArea.Value = textboxdata;
        end

        % Value changed function: DataDirEditField
        function DataDirEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            %this is where we take all our GUI variables and save them
            %as the appropriate value names and types to work with  
            %NiiStat as it was classically implemented by Chris and Grigori
            answer{1} = app.numperms.Value;
            GUIdata.numPermutations = answer{1};
            answer{2} = app.CorrectedpThresholdEditField.Value;
            GUIdata.correctedP = answer{2};
            answer{3} = app.minimumoverlapEditField.Value;
            GUIdata.minOverlap = answer{3};
            tempans = [];
                if app.voxelwiseButton.Value == 1 
                    tempans = cat(2,tempans,[0]);
                end
                if app.aichaButton.Value == 1 
                    tempans = cat(2,tempans,[1]);
                end
                if app.aalButton.Value == 1 
                    tempans = cat(2,tempans,[2]);
                end
                if app.aalcatButton.Value == 1 
                    tempans = cat(2,tempans,[3]);
                end
                if app.broButton.Value == 1 
                    tempans = cat(2,tempans,[4]);
                end
                if app.catButton.Value == 1 
                    tempans = cat(2,tempans,[5]);
                end
                if app.foxButton.Value == 1 
                    tempans = cat(2,tempans,[6]);
                end
                if app.jhuButton.Value == 1 
                    tempans = cat(2,tempans,[7]);
                end
            answer{4} = tempans;
            GUIdata.atlasChoice = answer{4};
            
            tempans = [];
            
                 if app.LESIONButton.Value == 1 
                    tempans = cat(2,tempans,[1]);
                 end
                 if app.CBFButton.Value == 1 
                    tempans = cat(2,tempans,[2]);
                 end
                 if app.RESTButton.Value == 1 
                    tempans = cat(2,tempans,[3]);
                 end
                 if app.i3mT1Button.Value == 1 
                    tempans = cat(2,tempans,[4]);
                 end
                 if app.i3mT2Button.Value == 1 
                    tempans = cat(2,tempans,[5]);
                 end
                 if app.DTIFAButton.Value == 1 
                    tempans = cat(2,tempans,[6]);
                 end
                 if app.DTIButton.Value == 1 
                    tempans = cat(2,tempans,[7]);
                 end
                 if app.DTIMDButton.Value == 1 
                    tempans = cat(2,tempans,[8]);
                 end
                 if app.TTPButton.Value == 1 
                    tempans = cat(2,tempans,[9]);
                 end
                 if app.MTTButton.Value == 1 
                    tempans = cat(2,tempans,[10]);
                 end
                 if app.CBVButton.Value == 1 
                    tempans = cat(2,tempans,[11]);
                 end
                 if app.DTIFCButton.Value == 1 
                    tempans = cat(2,tempans,[12]);
                 end
                 if app.fMRIButton.Value == 1 
                    tempans = cat(2,tempans,[13]);
                 end
                 if app.fMRIbButton.Value == 1 
                    tempans = cat(2,tempans,[14]);
                 end
                 if app.RESTalfButton.Value == 1 
                    tempans = cat(2,tempans,[15]);
                 end
                 if app.DTIMKButton.Value == 1 
                    tempans = cat(2,tempans,[16]);
                 end
                 if app.RESTpalfButton.Value == 1 
                    tempans = cat(2,tempans,[17]);
                 end
            answer{5} = tempans;
            GUIdata.modalityChoice = answer{5};
            
            tempans = [];
                if app.ExplicitVoxelMaskSwitch.Value == 'Yes'
                    tempans = cat(2,tempans,[1]);
                end
                if app.RegressLesionVolumeSwitch.Value == 'Yes'
                    tempans = cat(2,tempans,[2]);
                end
                if app.DeskewDataSwitch.Value == 'Yes'
                    tempans = cat(2,tempans,[3]);
                end
                if app.IncludeWMCSFconnectivitySwitch.Value == 'Yes'
                    tempans = cat(2,tempans,[4]);
                end
                if app.CustomROIsSwitch.Value == 'Yes'
                    tempans = cat(2,tempans,[5]);
                end
                if app.TFCESwitch.Value == 'Yes'
                    tempans = cat(2,tempans,[6]);
                end
                if app.ReportROIMeansSwitch.Value == 'Yes'
                    tempans = cat(2,tempans,[7]);
                end
                if app.SVMSwitch.Value == 'Yes'
                    tempans = cat(2,tempans,[8]);
                end
                if app.LowResolutionSwitch.Value == 'Yes'
                    tempans = cat(2,tempans,[9]);
                end
                if app.Button_20.Value == 1
                    tempans = cat(2,tempans,[10]);
                end
                if app.Button_21.Value == 1
                    tempans = cat(2,tempans,[11]);
                end
                if app.interhemispheric.Value == 1
                    tempans = cat(2,tempans,[12]);
                end
                
            answer{6} = tempans;
            GUIdata.specialChoice = answer{6};
             
            answer{7} = app.NameForResultsFolderEditField.Value;
            GUIdata.resultsFolderName = answer{7};
            
            %get my answers into the same format as the old NiiStat wants for their config file
            for i = 1:length(answer)
                answer{i} = num2str(answer{i});
            end
            answer = answer'
            %save over NiiStat cfg .mat file
            %%%%('niistat_cfg.mat', 'answer');
            
            GUIdata.dataDirectory = app.DataDirEditField.Value;
            GUIdata.outputDirectory = app.OutputDirEditField.Value;
            %make sure results are output to the OutpurDirectory
            cd(app.OutputDirEditField.Value);
            GUIdata.resultsFolder = app.NameForResultsFolderEditField.Value;
            GUIdata.excelFile = app.ExcelFileEditField.Value;
            GUIdata.chosenMask = app.ChosenMaskEditField.Value;
            
            
            GUIdata.testtype = app.testtype.SelectedObject.Text;
            %for extra chosen roi files, i.e. custom ones only
            GUIdata.chosenROis = app.chosenroifilesTextArea.Value;
            GUIdata.perms = app.numperms.Value;
            GUIdata.pvalue = app.CorrectedpThresholdEditField.Value;
            
            %gather and store selected ROIs 
            GUIdata.brodmann_picks = roiNames2roiNumbers(app.ROIs_1.Value);
            GUIdata.jhu_picks = roiNames2roiNumbers(app.ROIs_2.Value);
            GUIdata.aal_picks = roiNames2roiNumbers(app.ROIs_3.Value);
            GUIdata.cat_picks = roiNames2roiNumbers(app.ROIs_4.Value);
            GUIdata.aalcat_picks = roiNames2roiNumbers(app.ROIs_5.Value);
            GUIdata.aicha_picks = roiNames2roiNumbers(app.ROIs_6.Value);
            GUIdata.fox_picks = roiNames2roiNumbers(app.ROIs_7.Value);
            GUIdata.catani_picks = roiNames2roiNumbers(app.ROIs_8.Value);
            
            %ANY OTHER other data we need from the GUI to the .mat file ???
            GUIdata.useClassicNiiStat = app.UseNiiStatClassicCheckBox.Value;
            GUIdata.brodmann_picks_raw = app.ROIs_1.Value; %used for testing
            
            %save file, also make sure NiiStat knows how to find this file
            temp = which('NiiStatGUI');
            [filepath, name, ext] = fileparts(temp);
            save(fullfile(filepath,'niistatGUI_cfg.mat'),'GUIdata');
            
            GUIdata
            
            NiiStat;
            
           % close (app.UIFigure);
            
        end

        % Callback function
        function Play(app, event)
            %              [y, Fs] = audioread('backgroundMusic_01.mp3');
            %              player = audioplayer(y, Fs);
            %              play(player)
            %              x = {'triedtoplay'}
        end

        % Selection changed function: SELECTROISUBSETButtonGroup
        function ChangeROIList(app, event)
            selectedButton = app.SELECTROISUBSETButtonGroup.SelectedObject;
            app.ROIs_1.Items = {''};         app.TextArea_1.Value = '';
            app.ROIs_2.Items = {''};         app.TextArea_2.Value = '';
            app.ROIs_3.Items = {''};          app.TextArea_3.Value = '';
            app.ROIs_4.Items = {''};         app.TextArea_4.Value = '';
            app.ROIs_5.Items = {''};         app.TextArea_5.Value = '';
            app.ROIs_6.Items = {''};         app.TextArea_6.Value = '';
            app.ROIs_7.Items = {''};         app.TextArea_7.Value = '';
                
                
            if selectedButton == app.OriginalButton
              
               %roifiles = GetROIFilesGivenDirectory('roi/');
                
                app.ROIs_1.Items = GetROINamesFromFile(fullfile(GetROIPathGivenDirectoryName('roi/'),'bro.txt'),4);        app.TextArea_1.Value = 'Brodmann';
                app.ROIs_2.Items = GetROINamesFromFile(fullfile(GetROIPathGivenDirectoryName('roi/'),'jhu.txt'),4);        app.TextArea_2.Value = 'JHU';
                app.ROIs_3.Items = GetROINamesFromFile(fullfile(GetROIPathGivenDirectoryName('roi/'),'aal.txt'),4);        app.TextArea_3.Value = 'aal';
                app.ROIs_4.Items = GetROINamesFromFile(fullfile(GetROIPathGivenDirectoryName('roi/'),'cat.txt'),4);        app.TextArea_4.Value = 'cat';
                app.ROIs_5.Items = GetROINamesFromFile(fullfile(GetROIPathGivenDirectoryName('roi/'),'aalcat.txt'),4);     app.TextArea_5.Value = 'aalcat';
                app.ROIs_6.Items = GetROINamesFromFile(fullfile(GetROIPathGivenDirectoryName('roi/'),'AICHA.txt'),4);      app.TextArea_6.Value = 'AICHA';
                app.ROIs_7.Items = GetROINamesFromFile(fullfile(GetROIPathGivenDirectoryName('roi/'),'fox.txt'),4);        app.TextArea_7.Value = 'fox';
                  
            end
            
            if selectedButton == app.IowaButton
            
                %roifiles = GetROIFilesGivenDirectory('roi_uiowa/');
                
                app.ROIs_1.Items = GetROINamesFromFile(fullfile(GetROIPathGivenDirectoryName('roi_uiowa/'),'bro.txt'),4);          app.TextArea_1.Value = 'Brodmann';
                app.ROIs_2.Items = GetROINamesFromFile(fullfile(GetROIPathGivenDirectoryName('roi_uiowa/'),'jhu.txt'),4);          app.TextArea_2.Value = 'JHU';
                app.ROIs_3.Items = GetROINamesFromFile(fullfile(GetROIPathGivenDirectoryName('roi_uiowa/'),'aal.txt'),4);          app.TextArea_3.Value = 'aal';
                app.ROIs_4.Items = GetROINamesFromFile(fullfile(GetROIPathGivenDirectoryName('roi_uiowa/'),'cat.txt'),4);          app.TextArea_4.Value = 'cat';
                app.ROIs_5.Items = GetROINamesFromFile(fullfile(GetROIPathGivenDirectoryName('roi_uiowa/'),'aalcat.txt'),4);       app.TextArea_5.Value = 'aalcat';
                app.ROIs_7.Items = GetROINamesFromFile(fullfile(GetROIPathGivenDirectoryName('roi_uiowa/'),'fox.txt'),4);          app.TextArea_7.Value = 'fox';
            end
            
            
            if selectedButton == app.YoungAdultButton
            
                %roifiles = GetROIFilesGivenDirectory('roi_youngadults/');
            
                app.ROIs_1.Items = GetROINamesFromFile(fullfile(GetROIPathGivenDirectoryName('roi_youngadults/'),'bro.txt'),4);         app.TextArea_1.Value = 'Brodmann';
                app.ROIs_2.Items = GetROINamesFromFile(fullfile(GetROIPathGivenDirectoryName('roi_youngadults/'),'jhu.txt'),4);         app.TextArea_2.Value = 'JHU';
                app.ROIs_3.Items = GetROINamesFromFile(fullfile(GetROIPathGivenDirectoryName('roi_youngadults/'),'aal.txt'),4);         app.TextArea_3.Value = 'aal';
                app.ROIs_7.Items = GetROINamesFromFile(fullfile(GetROIPathGivenDirectoryName('roi_youngadults/'),'fox.txt'),4);         app.TextArea_7.Value = 'fox';
                app.ROIs_8.Items = GetROINamesFromFile(fullfile(GetROIPathGivenDirectoryName('roi_youngadults/'),'catani.txt'),4);       app.TextArea_8.Value = 'catani';
            end
       
            
        end

        % Value changed function: ROIs_1
        function ROIs_1ValueChanged(app, event)
            value = app.ROIs_1.Value
                    
        end

        % Value changed function: ROIs_2
        function ROIs_2ValueChanged(app, event)
            value = app.ROIs_2.Value;
            
        end

        % Value changed function: ROIs_3
        function ROIs_3ValueChanged(app, event)
            value = app.ROIs_3.Value;
            
        end

        % Value changed function: ROIs_4
        function ROIs_4ValueChanged(app, event)
            value = app.ROIs_4.Value;
            
        end

        % Value changed function: ROIs_5
        function ROIs_5ValueChanged(app, event)
            value = app.ROIs_5.Value;
            
        end

        % Value changed function: ROIs_6
        function ROIs_6ValueChanged(app, event)
            value = app.ROIs_6.Value;
            
        end

        % Value changed function: ROIs_7
        function ROIs_7ValueChanged(app, event)
            value = app.ROIs_7.Value;
            
        end

        % Value changed function: ROIs_8
        function ROIs_8ValueChanged(app, event)
            value = app.ROIs_8.Value;
            
        end

        % Value changed function: CustomROIsSwitch
        function CustomROIsSwitchValueChanged(app, event)
            value = app.CustomROIsSwitch.Value;
           
        
            if value == 'Yes' & app.broButton.Value == 1
                app.ROIs_1.Enable = 'on';
            else
                app.ROIs_1.Enable = 'off';
            end
            if value == 'Yes' & app.jhuButton.Value == 1
                app.ROIs_2.Enable = 'on';
            else
                app.ROIs_2.Enable = 'off';
            end
            if value == 'Yes' & app.aalButton.Value == 1
                app.ROIs_3.Enable = 'on';
            else
                app.ROIs_3.Enable = 'off';
            end
            if value == 'Yes' & app.catButton.Value == 1
                app.ROIs_4.Enable = 'on';
            else
                app.ROIs_4.Enable = 'off';
            end
              if value == 'Yes' & app.aalcatButton.Value == 1
                app.ROIs_5.Enable = 'on';
            else
                app.ROIs_5.Enable = 'off';
              end
                if value == 'Yes' & app.aichaButton.Value == 1
                app.ROIs_6.Enable = 'on';
            else
                app.ROIs_6.Enable = 'off';
                end
                  if value == 'Yes' & app.foxButton.Value == 1
                app.ROIs_7.Enable = 'on';
            else
                app.ROIs_7.Enable = 'off';
                  end
                    if value == 'Yes' & app.cataniButton.Value == 1
                app.ROIs_8.Enable = 'on';
            else
                app.ROIs_8.Enable = 'off';
            end
             
        end

        % Value changed function: broButton
        function broButtonValueChanged(app, event)
            value = app.broButton.Value;
            if value == 0 | app.CustomROIsSwitch.Value == '_No'
                app.ROIs_1.Enable = 'off';
             else
                app.ROIs_1.Enable = 'on';
             end  
        end

        % Value changed function: jhuButton
        function jhuButtonValueChanged(app, event)
            value = app.jhuButton.Value;
             if value == 0 | app.CustomROIsSwitch.Value == '_No'
                app.ROIs_2.Enable = 'off';
             else
                app.ROIs_2.Enable = 'on';
             end
        end

        % Value changed function: cataniButton
        function cataniButtonValueChanged(app, event)
            value = app.cataniButton.Value;
              if value == 0 | app.CustomROIsSwitch.Value == '_No'
                app.ROIs_8.Enable = 'off';
             else
                app.ROIs_8.Enable = 'on';
             end
            
        end

        % Value changed function: aalcatButton
        function aalcatButtonValueChanged(app, event)
            value = app.aalcatButton.Value;
             if value == 0 | app.CustomROIsSwitch.Value == '_No'
                app.ROIs_5.Enable = 'off';
             else
                app.ROIs_5.Enable = 'on';
             end
            
        end

        % Value changed function: catButton
        function catButtonValueChanged(app, event)
            value = app.catButton.Value;
             if value == 0 | app.CustomROIsSwitch.Value == '_No'
                app.ROIs_4.Enable = 'off';
             else
                app.ROIs_4.Enable = 'on';
             end
            
        end

        % Value changed function: aalButton
        function aalButtonValueChanged(app, event)
            value = app.aalButton.Value;
             if value == 0 | app.CustomROIsSwitch.Value == '_No'
                app.ROIs_3.Enable = 'off';
             else
                app.ROIs_3.Enable = 'on';
             end
        end

        % Value changed function: foxButton
        function foxButtonValueChanged(app, event)
            value = app.foxButton.Value;
              if value == 0 | app.CustomROIsSwitch.Value == '_No'
                app.ROIs_7.Enable = 'off';
             else
                app.ROIs_7.Enable = 'on';
             end
        end

        % Value changed function: aichaButton
        function aichaButtonValueChanged(app, event)
            value = app.aichaButton.Value;
              if value == 0 | app.CustomROIsSwitch.Value == '_No'
                app.ROIs_6.Enable = 'off';
             else
                app.ROIs_6.Enable = 'on';
             end
        end

        % Selection changed function: testtype
        function testtypeSelectionChanged(app, event)
            selectedButton = app.testtype.SelectedObject;
            if selectedButton.Text(1:3) == 'FDR'
               app.numperms.Enable = 'on';
               app.numperms.Value = -1;
               app.numperms.Enable = 'off';
            end
            if selectedButton.Text(1:3) == 'Bon'
               app.numperms.Enable = 'on';
               app.numperms.Value = 0;
               app.numperms.Enable = 'off';
            end
            if selectedButton.Text(1:3) == 'Nor'
               app.numperms.Enable = 'on';
               app.numperms.Value = 2000;
            end
             if selectedButton.Text(1:3) == 'Fre'
                app.numperms.Enable = 'on';
               app.numperms.Value = -2000;
            end
              
        end

        % Value changed function: numperms
        function numpermsValueChanged(app, event)
            value = app.numperms.Value;
            
            
        end

        % Value changed function: UseNiiStatClassicCheckBox
        function UseNiiStatClassicCheckBoxValueChanged(app, event)
            value = app.UseNiiStatClassicCheckBox.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MAINTab = uitab(app.TabGroup);
            app.MAINTab.Title = 'MAIN';
            app.MAINTab.Units = 'pixels';
            app.SelectExcelFileButton = uibutton(app.MAINTab, 'push');
            app.SelectExcelFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExcelFileButtonPushed, true);
            app.SelectExcelFileButton.Text = 'Select Excel File';
            app.ExcelFileLabel = uilabel(app.MAINTab);
            app.ExcelFileLabel.Text = 'Excel File  = ';
            app.ExcelFileEditField = uieditfield(app.MAINTab, 'text');
            app.NameForResultsFolderLabel = uilabel(app.MAINTab);
            app.NameForResultsFolderLabel.Text = 'Name For Results Folder  =';
            app.NameForResultsFolderEditField = uieditfield(app.MAINTab, 'text');
            app.NameForResultsFolderEditField.ValueChangedFcn = createCallbackFcn(app, @ResultsFolderNameEditFieldValueChanged, true);
            app.NameForResultsFolderEditField.Value = 'results';
            app.SelectOutputDirectoryButton = uibutton(app.MAINTab, 'push');
            app.SelectOutputDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputDirectoryButtonPushed, true);
            app.SelectOutputDirectoryButton.Text = 'Select Output Directory';
            app.OutputDirLabel = uilabel(app.MAINTab);
            app.OutputDirLabel.Text = 'Output Dir = ';
            app.OutputDirEditField = uieditfield(app.MAINTab, 'text');
            app.SelectDataDirectoryButton = uibutton(app.MAINTab, 'push');
            app.SelectDataDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataDirectoryButtonPushed, true);
            app.SelectDataDirectoryButton.Text = 'Select Data Directory';
            app.DataDirLabel = uilabel(app.MAINTab);
            app.DataDirLabel.Text = 'Data Dir    = ';
            app.DataDirEditField = uieditfield(app.MAINTab, 'text');
            app.DataDirEditField.ValueChangedFcn = createCallbackFcn(app, @DataDirEditFieldValueChanged, true);
            app.GOButton = uibutton(app.MAINTab, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = '<<< GO >>>';
            app.CorrectedpThresholdEditFieldLabel = uilabel(app.MAINTab);
            app.CorrectedpThresholdEditFieldLabel.Text = 'Corrected p Threshold';
            app.CorrectedpThresholdEditField = uieditfield(app.MAINTab, 'numeric');
            app.CorrectedpThresholdEditField.Limits = [1e-10 1];
            app.CorrectedpThresholdEditField.ValueDisplayFormat = '%11.6g';
            app.CorrectedpThresholdEditField.Value = 0.05;
            app.minimumoverlapEditFieldLabel = uilabel(app.MAINTab);
            app.minimumoverlapEditFieldLabel.Text = 'minimum overlap (e.g. how many participants must have signal in that area)';
            app.minimumoverlapEditField = uieditfield(app.MAINTab, 'numeric');
            app.minimumoverlapEditField.Limits = [1 10000];
            app.minimumoverlapEditField.Value = 1;
            app.numberofpermutationsifPermutationsorFreedmanLaneEditFieldLabel = uilabel(app.MAINTab);
            app.numberofpermutationsifPermutationsorFreedmanLaneEditFieldLabel.Text = 'number of permutations (if Permutations or Freedman Lane)';
            app.numperms = uieditfield(app.MAINTab, 'numeric');
            app.numperms.ValueChangedFcn = createCallbackFcn(app, @numpermsValueChanged, true);
            app.numperms.Limits = [-100000 100000];
            app.numperms.Value = 2000;
            app.testtype = uibuttongroup(app.MAINTab);
            app.testtype.SelectionChangedFcn = createCallbackFcn(app, @testtypeSelectionChanged, true);
            app.FDRButton = uiradiobutton(app.testtype);
            app.FDRButton.Text = 'FDR';
            app.FDRButton.Value = true;
            app.BoneferroniButton = uiradiobutton(app.testtype);
            app.BoneferroniButton.Text = 'Boneferroni';
            app.NormalPermutationButton = uiradiobutton(app.testtype);
            app.NormalPermutationButton.Text = 'Normal Permutation';
            app.FreedmanLanePermutationButton = uiradiobutton(app.testtype);
            app.FreedmanLanePermutationButton.Text = 'Freedman-Lane Permutation';
            app.Button_19 = uibutton(app.MAINTab, 'state');
            app.Button_19.Enable = 'off';
            app.Button_19.Icon = 'coollogo_com-3202760.png';
            app.Button_19.Text = '';
            app.TextArea_9 = uitextarea(app.MAINTab);
            app.TextArea_9.Editable = 'off';
            app.EditField_2 = uieditfield(app.MAINTab, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Value = 'Ver 1.0';
            app.UseNiiStatClassicCheckBox = uicheckbox(app.MAINTab);
            app.UseNiiStatClassicCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseNiiStatClassicCheckBoxValueChanged, true);
            app.UseNiiStatClassicCheckBox.Text = 'Use NiiStat Classic';
            app.ATLASTab = uitab(app.TabGroup);
            app.ATLASTab.Title = 'ATLAS';
            app.ATLASTab.Units = 'pixels';
            app.TextArea_1 = uitextarea(app.ATLASTab);
            app.TextArea_1.Value = {'brodmann'};
            app.ROIs_1 = uilistbox(app.ATLASTab);
            app.ROIs_1.Items = {};
            app.ROIs_1.Multiselect = 'on';
            app.ROIs_1.ValueChangedFcn = createCallbackFcn(app, @ROIs_1ValueChanged, true);
            app.ROIs_1.Enable = 'off';
            app.ROIs_1.Value = {};
            app.TextArea_2 = uitextarea(app.ATLASTab);
            app.TextArea_2.Value = {'jhu'};
            app.ROIs_2 = uilistbox(app.ATLASTab);
            app.ROIs_2.Items = {};
            app.ROIs_2.Multiselect = 'on';
            app.ROIs_2.ValueChangedFcn = createCallbackFcn(app, @ROIs_2ValueChanged, true);
            app.ROIs_2.Enable = 'off';
            app.ROIs_2.Value = {};
            app.aichaButton = uibutton(app.ATLASTab, 'state');
            app.aichaButton.ValueChangedFcn = createCallbackFcn(app, @aichaButtonValueChanged, true);
            app.aichaButton.Icon = 'roundbuttonblue.png';
            app.aichaButton.Text = '';
            app.TextArea_6 = uitextarea(app.ATLASTab);
            app.TextArea_6.Value = {'aicha'};
            app.ROIs_6 = uilistbox(app.ATLASTab);
            app.ROIs_6.Items = {};
            app.ROIs_6.Multiselect = 'on';
            app.ROIs_6.ValueChangedFcn = createCallbackFcn(app, @ROIs_6ValueChanged, true);
            app.ROIs_6.Enable = 'off';
            app.ROIs_6.Value = {};
            app.foxButton = uibutton(app.ATLASTab, 'state');
            app.foxButton.ValueChangedFcn = createCallbackFcn(app, @foxButtonValueChanged, true);
            app.foxButton.Icon = 'roundbuttonblue.png';
            app.foxButton.Text = '';
            app.TextArea_7 = uitextarea(app.ATLASTab);
            app.TextArea_7.Value = {'fox'};
            app.ROIsListBoxLabel_4 = uilabel(app.ATLASTab);
            app.ROIsListBoxLabel_4.Text = 'ROIs';
            app.ROIs_7 = uilistbox(app.ATLASTab);
            app.ROIs_7.Items = {};
            app.ROIs_7.Multiselect = 'on';
            app.ROIs_7.ValueChangedFcn = createCallbackFcn(app, @ROIs_7ValueChanged, true);
            app.ROIs_7.Enable = 'off';
            app.ROIs_7.Value = {};
            app.aalButton = uibutton(app.ATLASTab, 'state');
            app.aalButton.ValueChangedFcn = createCallbackFcn(app, @aalButtonValueChanged, true);
            app.aalButton.Icon = 'roundbuttonblue.png';
            app.aalButton.Text = '';
            app.TextArea_3 = uitextarea(app.ATLASTab);
            app.TextArea_3.Value = {'aal'};
            app.catButton = uibutton(app.ATLASTab, 'state');
            app.catButton.ValueChangedFcn = createCallbackFcn(app, @catButtonValueChanged, true);
            app.catButton.Icon = 'roundbuttonblue.png';
            app.catButton.Text = '';
            app.TextArea_4 = uitextarea(app.ATLASTab);
            app.TextArea_4.Value = {'cat'};
            app.ROIs_4 = uilistbox(app.ATLASTab);
            app.ROIs_4.Items = {};
            app.ROIs_4.Multiselect = 'on';
            app.ROIs_4.ValueChangedFcn = createCallbackFcn(app, @ROIs_4ValueChanged, true);
            app.ROIs_4.Enable = 'off';
            app.ROIs_4.Value = {};
            app.aalcatButton = uibutton(app.ATLASTab, 'state');
            app.aalcatButton.ValueChangedFcn = createCallbackFcn(app, @aalcatButtonValueChanged, true);
            app.aalcatButton.Icon = 'roundbuttonblue.png';
            app.aalcatButton.Text = '';
            app.TextArea_5 = uitextarea(app.ATLASTab);
            app.TextArea_5.Value = {'aalcat'};
            app.ROIs_5 = uilistbox(app.ATLASTab);
            app.ROIs_5.Items = {};
            app.ROIs_5.Multiselect = 'on';
            app.ROIs_5.ValueChangedFcn = createCallbackFcn(app, @ROIs_5ValueChanged, true);
            app.ROIs_5.Enable = 'off';
            app.ROIs_5.Value = {};
            app.voxelwiseButton = uibutton(app.ATLASTab, 'state');
            app.voxelwiseButton.Icon = 'voxels.png';
            app.voxelwiseButton.Text = '';
            app.ROIs_3 = uilistbox(app.ATLASTab);
            app.ROIs_3.Items = {};
            app.ROIs_3.Multiselect = 'on';
            app.ROIs_3.ValueChangedFcn = createCallbackFcn(app, @ROIs_3ValueChanged, true);
            app.ROIs_3.Enable = 'off';
            app.ROIs_3.Value = {};
            app.jhuButton = uibutton(app.ATLASTab, 'state');
            app.jhuButton.ValueChangedFcn = createCallbackFcn(app, @jhuButtonValueChanged, true);
            app.jhuButton.Icon = 'roundbuttonblue.png';
            app.jhuButton.Text = '';
            app.broButton = uibutton(app.ATLASTab, 'state');
            app.broButton.ValueChangedFcn = createCallbackFcn(app, @broButtonValueChanged, true);
            app.broButton.Icon = 'roundbuttonblue.png';
            app.broButton.Text = '';
            app.EditField = uieditfield(app.ATLASTab, 'text');
            app.EditField.Value = 'All voxels will be analyzed...';
            app.SELECTROISUBSETButtonGroup = uibuttongroup(app.ATLASTab);
            app.SELECTROISUBSETButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChangeROIList, true);
            app.SELECTROISUBSETButtonGroup.Title = 'SELECT ROI SUBSET';
            app.OriginalButton = uitogglebutton(app.SELECTROISUBSETButtonGroup);
            app.OriginalButton.Text = 'Original';
            app.OriginalButton.Value = true;
            app.IowaButton = uitogglebutton(app.SELECTROISUBSETButtonGroup);
            app.IowaButton.Text = 'Iowa';
            app.YoungAdultButton = uitogglebutton(app.SELECTROISUBSETButtonGroup);
            app.YoungAdultButton.Text = 'Young Adult';
            app.TextArea_VOXELWISE = uitextarea(app.ATLASTab);
            app.TextArea_VOXELWISE.Value = {'Voxel Wise'; ''};
            app.cataniButton = uibutton(app.ATLASTab, 'state');
            app.cataniButton.ValueChangedFcn = createCallbackFcn(app, @cataniButtonValueChanged, true);
            app.cataniButton.Icon = 'roundbuttonblue.png';
            app.cataniButton.Text = '';
            app.TextArea_8 = uitextarea(app.ATLASTab);
            app.TextArea_8.Value = {'catani'};
            app.ROIs_8 = uilistbox(app.ATLASTab);
            app.ROIs_8.Items = {};
            app.ROIs_8.Multiselect = 'on';
            app.ROIs_8.ValueChangedFcn = createCallbackFcn(app, @ROIs_8ValueChanged, true);
            app.ROIs_8.Enable = 'off';
            app.ROIs_8.Value = {};
            app.CustomROIsSwitchLabel = uilabel(app.ATLASTab);
            app.CustomROIsSwitchLabel.Text = 'Custom ROIs';
            app.CustomROIsSwitch = uiswitch(app.ATLASTab, 'toggle');
            app.CustomROIsSwitch.Items = {'_No', 'Yes'};
            app.CustomROIsSwitch.ValueChangedFcn = createCallbackFcn(app, @CustomROIsSwitchValueChanged, true);
            app.CustomROIsSwitch.Value = '_No';
            app.MODALITYTab = uitab(app.TabGroup);
            app.MODALITYTab.Title = 'MODALITY';
            app.MODALITYTab.Units = 'pixels';
            app.LESIONButton = uibutton(app.MODALITYTab, 'state');
            app.LESIONButton.Text = 'LESION';
            app.CBFButton = uibutton(app.MODALITYTab, 'state');
            app.CBFButton.Text = 'CBF';
            app.RESTButton = uibutton(app.MODALITYTab, 'state');
            app.RESTButton.Text = 'REST';
            app.i3mT1Button = uibutton(app.MODALITYTab, 'state');
            app.i3mT1Button.Text = 'i3mT1';
            app.MTTButton = uibutton(app.MODALITYTab, 'state');
            app.MTTButton.Text = 'MTT';
            app.DTIMDButton = uibutton(app.MODALITYTab, 'state');
            app.DTIMDButton.Text = 'DTI (MD)';
            app.TTPButton = uibutton(app.MODALITYTab, 'state');
            app.TTPButton.Text = 'TTP';
            app.DTIFAButton = uibutton(app.MODALITYTab, 'state');
            app.DTIFAButton.Text = 'DTI (FA)';
            app.i3mT2Button = uibutton(app.MODALITYTab, 'state');
            app.i3mT2Button.Text = 'i3mT2';
            app.CBVButton = uibutton(app.MODALITYTab, 'state');
            app.CBVButton.Text = 'CBV';
            app.DTIFCButton = uibutton(app.MODALITYTab, 'state');
            app.DTIFCButton.Text = 'DTI (FC)';
            app.fMRIButton = uibutton(app.MODALITYTab, 'state');
            app.fMRIButton.Text = 'fMRI';
            app.fMRIbButton = uibutton(app.MODALITYTab, 'state');
            app.fMRIbButton.Text = 'fMRIb';
            app.RESTalfButton = uibutton(app.MODALITYTab, 'state');
            app.RESTalfButton.Text = 'REST-alf';
            app.RESTpalfButton = uibutton(app.MODALITYTab, 'state');
            app.RESTpalfButton.Text = 'REST-palf';
            app.DTIMKButton = uibutton(app.MODALITYTab, 'state');
            app.DTIMKButton.Text = 'DTI (MK)';
            app.Button_11 = uibutton(app.MODALITYTab, 'state');
            app.Button_11.Icon = 'LESION.png';
            app.Button_11.Text = '';
            app.Button_12 = uibutton(app.MODALITYTab, 'state');
            app.Button_12.Icon = 'fmri.jpg';
            app.Button_12.Text = '';
            app.Button_13 = uibutton(app.MODALITYTab, 'state');
            app.Button_13.Icon = 'download.jpeg';
            app.Button_13.Text = '';
            app.Button_14 = uibutton(app.MODALITYTab, 'state');
            app.Button_14.Icon = 'cbf.png';
            app.Button_14.Text = '';
            app.Button_15 = uibutton(app.MODALITYTab, 'state');
            app.Button_15.Text = '';
            app.Button_16 = uibutton(app.MODALITYTab, 'state');
            app.Button_16.Text = '';
            app.DTIButton = uibutton(app.MODALITYTab, 'state');
            app.DTIButton.Text = 'DTI';
            app.Button_18 = uibutton(app.MODALITYTab, 'state');
            app.Button_18.Text = '';
            app.SPECIALTab = uitab(app.TabGroup);
            app.SPECIALTab.Title = 'SPECIAL';
            app.SPECIALTab.Units = 'pixels';
            app.Button_20 = uibutton(app.SPECIALTab, 'state');
            app.Button_20.Icon = 'left.png';
            app.Button_20.Text = '';
            app.Button_21 = uibutton(app.SPECIALTab, 'state');
            app.Button_21.Icon = 'right.png';
            app.Button_21.Text = '';
            app.interhemispheric = uibutton(app.SPECIALTab, 'state');
            app.interhemispheric.Icon = 'InterHemispheric.png';
            app.interhemispheric.Text = '';
            app.ExplicitVoxelMaskSwitchLabel = uilabel(app.SPECIALTab);
            app.ExplicitVoxelMaskSwitchLabel.Text = 'Explicit Voxel Mask';
            app.ExplicitVoxelMaskSwitch = uiswitch(app.SPECIALTab, 'toggle');
            app.ExplicitVoxelMaskSwitch.Items = {'_No', 'Yes'};
            app.ExplicitVoxelMaskSwitch.Value = '_No';
            app.RegressLesionVolumeSwitchLabel = uilabel(app.SPECIALTab);
            app.RegressLesionVolumeSwitchLabel.Text = 'Regress Lesion Volume';
            app.RegressLesionVolumeSwitch = uiswitch(app.SPECIALTab, 'toggle');
            app.RegressLesionVolumeSwitch.Items = {'_No', 'Yes'};
            app.RegressLesionVolumeSwitch.Value = '_No';
            app.DeskewDataSwitchLabel = uilabel(app.SPECIALTab);
            app.DeskewDataSwitchLabel.Text = 'De-skew Data';
            app.DeskewDataSwitch = uiswitch(app.SPECIALTab, 'toggle');
            app.DeskewDataSwitch.Items = {'_No', 'Yes'};
            app.DeskewDataSwitch.Value = '_No';
            app.IncludeWMCSFconnectivitySwitchLabel = uilabel(app.SPECIALTab);
            app.IncludeWMCSFconnectivitySwitchLabel.Text = 'Include WM/CSF connectivity';
            app.IncludeWMCSFconnectivitySwitch = uiswitch(app.SPECIALTab, 'toggle');
            app.IncludeWMCSFconnectivitySwitch.Items = {'_No', 'Yes'};
            app.IncludeWMCSFconnectivitySwitch.Value = '_No';
            app.TFCESwitchLabel = uilabel(app.SPECIALTab);
            app.TFCESwitchLabel.Text = 'TFCE';
            app.TFCESwitch = uiswitch(app.SPECIALTab, 'toggle');
            app.TFCESwitch.Items = {'_No', 'Yes'};
            app.TFCESwitch.Value = '_No';
            app.ReportROIMeansSwitchLabel = uilabel(app.SPECIALTab);
            app.ReportROIMeansSwitchLabel.Text = 'Report ROI Means';
            app.ReportROIMeansSwitch = uiswitch(app.SPECIALTab, 'toggle');
            app.ReportROIMeansSwitch.Items = {'_No', 'Yes'};
            app.ReportROIMeansSwitch.Value = '_No';
            app.SVMSwitchLabel = uilabel(app.SPECIALTab);
            app.SVMSwitchLabel.Text = 'SVM';
            app.SVMSwitch = uiswitch(app.SPECIALTab, 'toggle');
            app.SVMSwitch.Items = {'_No', 'Yes'};
            app.SVMSwitch.Value = '_No';
            app.LowResolutionSwitchLabel = uilabel(app.SPECIALTab);
            app.LowResolutionSwitchLabel.Text = 'Low Resolution';
            app.LowResolutionSwitch = uiswitch(app.SPECIALTab, 'toggle');
            app.LowResolutionSwitch.Items = {'_No', 'Yes'};
            app.LowResolutionSwitch.Value = '_No';
            app.ChooseMaskButton = uibutton(app.SPECIALTab, 'push');
            app.ChooseMaskButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseMaskButtonPushed, true);
            app.ChooseMaskButton.Text = 'Choose Mask';
            app.ChosenMaskEditField = uieditfield(app.SPECIALTab, 'text');
            app.ChooseCustomROIsFilesinadditiontoatlasesButton = uibutton(app.SPECIALTab, 'push');
            app.ChooseCustomROIsFilesinadditiontoatlasesButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseCustomROIsFilesinadditiontoatlasesButtonPushed, true);
            app.ChooseCustomROIsFilesinadditiontoatlasesButton.Text = 'Choose Custom ROI(s) Files (in addition to atlases)';
            app.ClearMaskButton = uibutton(app.SPECIALTab, 'push');
            app.ClearMaskButton.Text = 'Clear Mask';
            app.ClearROIsButton = uibutton(app.SPECIALTab, 'push');
            app.ClearROIsButton.Text = 'Clear ROI(s)';
            app.chosenroifilesTextArea = uitextarea(app.SPECIALTab);
            app.LimitanalysistolefthemisphererighthemisphereorbilateralLabel = uilabel(app.SPECIALTab);
            app.LimitanalysistolefthemisphererighthemisphereorbilateralLabel.Text = 'Limit analysis to left hemisphere, right hemisphere or bilateral';
            app.SPECIALTab_2 = uitab(app.TabGroup);
            app.SPECIALTab_2.Title = 'SPECIAL+';
            app.SPECIALTab_2.Units = 'pixels';
        end
    end

    methods (Access = public)

        % Construct app
        function app = NiiStatGUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFunction)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end